classdef ROMapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ModelOrderReductionToolUIFigure  matlab.ui.Figure
        SaveROMButton                  matlab.ui.control.Button
        SpecifyROMsizeEditField        matlab.ui.control.NumericEditField
        SpecifyROMsizeEditFieldLabel   matlab.ui.control.Label
        GenerateROMLabel               matlab.ui.control.Label
        RetainDropDown                 matlab.ui.control.DropDown
        RetainDropDownLabel            matlab.ui.control.Label
        TestsystemsizesEditField       matlab.ui.control.EditField
        TestsystemsizesEditFieldLabel  matlab.ui.control.Label
        ROMDataLabel                   matlab.ui.control.Label
        StatusunknownLabel             matlab.ui.control.Label
        ErrortargetEditField           matlab.ui.control.NumericEditField
        ErrortargetEditFieldLabel      matlab.ui.control.Label
        CheckROMstatusButton           matlab.ui.control.Button
        GenerateerrorplotButton        matlab.ui.control.Button
        FrequencyRangeofInterestEditField  matlab.ui.control.EditField
        FrequencyRangeofInterestradsEditFieldLabel  matlab.ui.control.Label
        ROMOptionsLabel                matlab.ui.control.Label
        CurrentROMEditField            matlab.ui.control.EditField
        CurrentROMEditFieldLabel       matlab.ui.control.Label
        SourcemodelEditField           matlab.ui.control.EditField
        SourcemodelEditFieldLabel      matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        hActiveROM % Error plot active ROM error
        hErrorBound % Error plot error bound
        hErrorPlot % Error plot
        projdir % thermdir from base workspace
        datadir % save path to data directory w/ OS specific filesep
    end
    
    methods (Access = private)
        
        function tf = isFile1OlderThan2(app,file1,file2)
            f1 = dir(file1);
            f2 = dir(file2);
            tf = f1.datenum <= (f2.datenum + (2/86400)); % 2 second tol
        end
        
        function updateErrorBound(app)
            errtol = app.ErrortargetEditField.Value;
            xl = xlim(app.UIAxes);
            set(app.hErrorBound,'Visible','off')
            if(verLessThan('matlab','9.11'))
                app.hErrorBound = plot(app.UIAxes,[-xl(2) 2*xl(2)], ...
                                   [errtol errtol],'k--');
            else
                app.hErrorBound = boundaryline(app.UIAxes,[-xl(2) 2*xl(2)], ...
                                   [errtol errtol],'FlipBoundary','on');
            end
            set(app.hErrorBound,'Visible','on')
            xlim(app.UIAxes,xl);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % define app member variables
            app.projdir = evalin('base','thermdir');
            app.datadir = fullfile(app.projdir,'data');
            % auto populate text fields
            if (isfile(fullfile(app.datadir,'baseline.mat')))
                app.SourcemodelEditField.Value = 'baseline.mat';
            end
            if (isfile(fullfile(app.datadir,'ROM.mat')))
                app.CurrentROMEditField.Value = 'ROM.mat';
            end
            % get current dim of current ROM
            if(isfile(fullfile(app.datadir,app.CurrentROMEditField.Value)))
                load(fullfile(app.datadir,app.CurrentROMEditField.Value),'rsys');
                rsize = size(rsys.A,1);
                app.SpecifyROMsizeEditField.Value = rsize;
            end
        end

        % Button pushed function: CheckROMstatusButton
        function CheckROMstatusButtonPushed(app, event)
            % retrieve filenames
            srcfile = app.SourcemodelEditField.Value;
            ROMfile = app.CurrentROMEditField.Value;
            % ensure source file is given
            if (isempty(srcfile))
                msgbox('Error: source MAT file required');
                return;
            else
                srcfile = fullfile(app.datadir,srcfile);
            end
            % if no ROM provided, prompt to generate
            if (isempty(ROMfile))
                msgbox('Generate ROM using "Save ROM" button below');
                return;
            else
                ROMfile = fullfile(app.datadir,ROMfile);
                app.StatusunknownLabel.FontWeight = 'bold';
                if (isFile1OlderThan2(app,srcfile,ROMfile))
                    msg = 'ROM up to date';
                    app.StatusunknownLabel.Text = msg;
                    app.StatusunknownLabel.FontColor = 'g';
                else
                    msg = 'ROM out of date';
                    app.StatusunknownLabel.Text = msg;
                    app.StatusunknownLabel.FontColor = 'r';
                end
            end
        end

        % Button pushed function: GenerateerrorplotButton
        function GenerateerrorplotButtonPushed(app, event)
            % ensure source file is given
            if (isempty(app.SourcemodelEditField.Value))
                msgbox('Error: source MAT file required');
                return;
            else
                FEfile = fullfile(app.datadir,app.SourcemodelEditField.Value);
            end
        
            % reduce order of source model
            wb = 0;
            f = waitbar(wb,'Running ROM error analysis...');
            % first set test parameters
            test_params;
            load(FEfile,'sys');
            r = str2num(app.TestsystemsizesEditField.Value);
            nr = length(r);
            freqRange = str2num(app.FrequencyRangeofInterestEditField.Value);
            wb = wb+0.1;
            f = waitbar(wb,f,'Generating reduced order model...');
            rom0file = fullfile(app.datadir,'ROM0.mat');
            if(isfile(rom0file) && isFile1OlderThan2(app,FEfile,rom0file))
                load(rom0file,'rsys0');
                minfreq = floor(rsys0.lambda(1));
                maxfreq = ceil(rsys0.lambda(end));
                % check if user requests data outside of previously calculated range
                if(freqRange(1)<minfreq || freqRange(2)>maxfreq)
                    rsys0 = modalTruncation(sys,freqRange);
                    rsys0 = rmfield(rsys0,{'type','A','B','C','D','E'});
                    save(rom0file,'rsys0');
                end
            else
                rsys0 = modalTruncation(sys,freqRange);
                rsys0 = rmfield(rsys0,{'type','A','B','C','D','E'});
                save(rom0file,'rsys0');
            end
            
            errfile = fullfile(app.datadir,'errorAnalysisResults.mat');
            if (isfile(errfile) && isFile1OlderThan2(app,FEfile,errfile))
                load(errfile,'r','rfreq','rerrors');
                warning('MyRepo:ExistingErrorUsed','Error plot is using existing analysis with test sizes [%s]. To use different sizes, delete errorAnalysisResults.mat',num2str(r))
            else
                warning('MyRepo:WarnLongTime',['There is no saved error analysis or the specified ROM out of date.' ...
                ' A new error analysis will now run and can take over 10 minutes depending on the size of the test.'])
                % get baseline data
                blresfile = fullfile(app.datadir,'baselineResults.mat');
                wb = wb+0.1;
                f = waitbar(wb,f,'Getting source model transient results...');
                if (isfile(blresfile) && isFile1OlderThan2(app,FEfile,blresfile))
                    load(blresfile,'baseline');
                else
                    % getTransientResults for baseline
                    ICvec = ICval*ones(size(sys.A,1),1);
                    baseline = getTransientResults(tmdl,blk,sys,ICvec);
                    save(blresfile,'baseline')
                end
                % simulate test space
                rfreq = zeros(1,nr);
                rerrors = zeros(1,nr);
                rtiming = zeros(1,nr);
                wb = wb + 0.3;
                wb0 = wb;
                for i = 1:nr
                    wb = wb0 + (i/nr)*(0.95-wb0);
                    f = waitbar(wb,f,'Getting ROM transient results...');
                    % retain r modes and compute reduced matrices
                    ri = r(i);
                    Vi = rsys0.V(:,1:ri);
                    tsys.A = Vi'*sys.A*Vi;
                    tsys.B = Vi'*sys.B   ;
                    tsys.C =     sys.C*Vi;
                    tsys.D =     sys.D   ;
                    tsys.E = Vi'*sys.E*Vi;
                    ICvec = (ICval*ones(1,size(Vi,1)))*Vi;
                    outi = getTransientResults(tmdl,blk,tsys,ICvec);
                    rfreq(i) = rsys0.lambda(ri);
                    rtiming(i) = outi.SimulationMetadata.TimingInfo.TotalElapsedWallTime;
                    rerrors(i) = norm(baseline.yout-outi.yout,normtype)/ ...
                                norm(baseline.yout,normtype);
                end
                save(errfile,'r','rfreq','rerrors','rtiming')
            end
            close(f);
            % plot relative transient errors
            hold(app.UIAxes,'on')
            set(app.hErrorPlot,'Visible','off')
            app.hErrorPlot = plot(app.UIAxes,rfreq,rerrors*100, ...
                                  'b','LineWidth',1.5);
            set(app.hErrorPlot,'Visible','on')
            app.updateErrorBound;
            hold(app.UIAxes,'off')
            % plot current ROM maxfreq if available
            if(isfile(fullfile(app.datadir,app.CurrentROMEditField.Value)))
                load(fullfile(app.datadir,app.CurrentROMEditField.Value),'rsys');
                rsize = size(rsys.V,2);
            else
                rsize = size(rsys0.V,2);
            end
            app.SpecifyROMsizeEditField.Value = rsize;
            yl = ylim(app.UIAxes);
            set(app.hActiveROM,'Visible','off')
            hold(app.UIAxes,'on')
            app.hActiveROM = plot(app.UIAxes,...
                [rsys0.lambda(rsize) rsys0.lambda(rsize)],...
                yl,'r');
            set(app.hActiveROM,'Visible','on')
            ylim(app.UIAxes,yl);
                hold(app.UIAxes,'off')
        end

        % Value changed function: SpecifyROMsizeEditField
        function SpecifyROMsizeEditFieldValueChanged(app, event)
            if (~isfile(fullfile(app.datadir,'ROM0.mat')))
                %message('Run "Generate error plot" first');
                return;
            end
            value = app.SpecifyROMsizeEditField.Value;
            load(fullfile(app.datadir,'ROM0.mat'),'rsys0')
            yl = ylim(app.UIAxes);
            set(app.hActiveROM,'Visible','off')
            hold(app.UIAxes,'on')
            app.hActiveROM = plot(app.UIAxes,...
                [rsys0.lambda(value) rsys0.lambda(value)],...
                yl,'r');
            set(app.hActiveROM,'Visible','on')
            ylim(app.UIAxes,yl);
            hold(app.UIAxes,'off')
        end

        % Value changed function: ErrortargetEditField
        function ErrortargetEditFieldValueChanged(app, event)
            value = app.ErrortargetEditField.Value;
            yl = ylim(app.UIAxes);
            xl = xlim(app.UIAxes);
            set(app.hErrorBound,'Visible','off')
            hold(app.UIAxes,'on')
            app.hErrorBound = boundaryline(app.UIAxes,[-xl(2) 2*xl(2)], ...
                                       [value value],'FlipBoundary','on');
            set(app.hErrorBound,'Visible','on')
            xlim(app.UIAxes,xl);
            ylim(app.UIAxes,yl);
            hold(app.UIAxes,'off')
        end

        % Button pushed function: SaveROMButton
        function SaveROMButtonPushed(app, event)
            rsize = app.SpecifyROMsizeEditField.Value;
            rsys0 = struct;
            load(fullfile(app.datadir,app.SourcemodelEditField.Value),'sys');
            if(isfile(fullfile(app.datadir,'ROM0.mat')))
                load(fullfile(app.datadir,'ROM0.mat'),'rsys0');
            else
                msgbox('No ROM found! Try "Generate error plot" to generare reduced model.');
                return;
            end
            Vi = rsys0.V(:,1:rsize);
            rsys.A = Vi'*sys.A*Vi;
            rsys.B = Vi'*sys.B   ;
            rsys.C =     sys.C*Vi;
            rsys.D =     sys.D   ;
            rsys.E = Vi'*sys.E*Vi;
            rsys.V = Vi;
            rsys.lambda = rsys0.lambda(1:rsize);
            assignin("base","rsys",rsys);
            save(fullfile(app.datadir,'ROM.mat'),'rsys')
            app.CurrentROMEditField.Value = 'ROM.mat';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ModelOrderReductionToolUIFigure = uifigure('Visible', 'off');
            app.ModelOrderReductionToolUIFigure.Name = 'Model Order Reduction Tool';
            app.UIAxes = uiaxes(app.ModelOrderReductionToolUIFigure);
            xlabel(app.UIAxes, 'Maximum frequency (rad/s)')
            ylabel(app.UIAxes, 'Relative Error (%)')
            zlabel(app.UIAxes, 'Z')
            app.SourcemodelEditFieldLabel = uilabel(app.ModelOrderReductionToolUIFigure);
            app.SourcemodelEditFieldLabel.Text = 'Source model';
            app.SourcemodelEditField = uieditfield(app.ModelOrderReductionToolUIFigure, 'text');
            app.CurrentROMEditFieldLabel = uilabel(app.ModelOrderReductionToolUIFigure);
            app.CurrentROMEditFieldLabel.Text = 'Current ROM';
            app.CurrentROMEditField = uieditfield(app.ModelOrderReductionToolUIFigure, 'text');
            app.ROMOptionsLabel = uilabel(app.ModelOrderReductionToolUIFigure);
            app.ROMOptionsLabel.Text = 'ROM Options';
            app.FrequencyRangeofInterestradsEditFieldLabel = uilabel(app.ModelOrderReductionToolUIFigure);
            app.FrequencyRangeofInterestradsEditFieldLabel.Text = 'Frequency Range of Interest (rad/s)';
            app.FrequencyRangeofInterestEditField = uieditfield(app.ModelOrderReductionToolUIFigure, 'text');
            app.FrequencyRangeofInterestEditField.Value = '[0 200]';
            app.GenerateerrorplotButton = uibutton(app.ModelOrderReductionToolUIFigure, 'push');
            app.GenerateerrorplotButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateerrorplotButtonPushed, true);
            app.GenerateerrorplotButton.Text = 'Generate error plot';
            app.CheckROMstatusButton = uibutton(app.ModelOrderReductionToolUIFigure, 'push');
            app.CheckROMstatusButton.ButtonPushedFcn = createCallbackFcn(app, @CheckROMstatusButtonPushed, true);
            app.CheckROMstatusButton.Text = 'Check ROM status';
            app.ErrortargetEditFieldLabel = uilabel(app.ModelOrderReductionToolUIFigure);
            app.ErrortargetEditFieldLabel.Text = 'Error target (%)';
            app.ErrortargetEditField = uieditfield(app.ModelOrderReductionToolUIFigure, 'numeric');
            app.ErrortargetEditField.ValueChangedFcn = createCallbackFcn(app, @ErrortargetEditFieldValueChanged, true);
            app.ErrortargetEditField.Value = 1;
            app.StatusunknownLabel = uilabel(app.ModelOrderReductionToolUIFigure);
            app.StatusunknownLabel.Text = 'Status unknown';
            app.ROMDataLabel = uilabel(app.ModelOrderReductionToolUIFigure);
            app.ROMDataLabel.Text = 'ROM Data';
            app.TestsystemsizesEditFieldLabel = uilabel(app.ModelOrderReductionToolUIFigure);
            app.TestsystemsizesEditFieldLabel.Text = 'Test system sizes';
            app.TestsystemsizesEditField = uieditfield(app.ModelOrderReductionToolUIFigure, 'text');
            app.TestsystemsizesEditField.Value = '[32 64 128 256 512]';
            app.RetainDropDownLabel = uilabel(app.ModelOrderReductionToolUIFigure);
            app.RetainDropDownLabel.Text = 'Retain:';
            app.RetainDropDown = uidropdown(app.ModelOrderReductionToolUIFigure);
            app.RetainDropDown.Items = {'Lowest frequencies'};
            app.RetainDropDown.Value = 'Lowest frequencies';
            app.GenerateROMLabel = uilabel(app.ModelOrderReductionToolUIFigure);
            app.GenerateROMLabel.Text = 'Generate ROM';
            app.SpecifyROMsizeEditFieldLabel = uilabel(app.ModelOrderReductionToolUIFigure);
            app.SpecifyROMsizeEditFieldLabel.Text = 'Specify ROM size';
            app.SpecifyROMsizeEditField = uieditfield(app.ModelOrderReductionToolUIFigure, 'numeric');
            app.SpecifyROMsizeEditField.ValueChangedFcn = createCallbackFcn(app, @SpecifyROMsizeEditFieldValueChanged, true);
            app.SpecifyROMsizeEditField.Value = 2;
            app.SaveROMButton = uibutton(app.ModelOrderReductionToolUIFigure, 'push');
            app.SaveROMButton.ButtonPushedFcn = createCallbackFcn(app, @SaveROMButtonPushed, true);
            app.SaveROMButton.Text = 'Save ROM';
            % Show the figure after all components are created
            app.ModelOrderReductionToolUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ROMapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ModelOrderReductionToolUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ModelOrderReductionToolUIFigure)
        end
    end
end